classdef RLC_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectCircuitTypeLabel     matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        SeriesTab                  matlab.ui.container.Tab
        sLabel_4                   matlab.ui.control.Label
        sLabel_3                   matlab.ui.control.Label
        endLabel_2                 matlab.ui.control.Label
        startLabel_2               matlab.ui.control.Label
        tEditField_4               matlab.ui.control.NumericEditField
        tEditField_4Label          matlab.ui.control.Label
        tEditField_3               matlab.ui.control.NumericEditField
        tEditField_3Label          matlab.ui.control.Label
        SelectPanel                matlab.ui.container.Panel
        VSCheckBox                 matlab.ui.control.CheckBox
        VCCheckBox                 matlab.ui.control.CheckBox
        VLCheckBox                 matlab.ui.control.CheckBox
        VRCheckBox                 matlab.ui.control.CheckBox
        SignalDropDown             matlab.ui.control.DropDown
        SignalDropDownLabel        matlab.ui.control.Label
        RLCValuesPanel             matlab.ui.container.Panel
        HzLabel                    matlab.ui.control.Label
        FrequencyEditField         matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel    matlab.ui.control.Label
        VLabel                     matlab.ui.control.Label
        VsEditField                matlab.ui.control.NumericEditField
        VsEditFieldLabel           matlab.ui.control.Label
        FLabel                     matlab.ui.control.Label
        HLabel                     matlab.ui.control.Label
        CEditField                 matlab.ui.control.NumericEditField
        CEditFieldLabel            matlab.ui.control.Label
        LEditField                 matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        ParallelTab                matlab.ui.container.Tab
        sLabel_2                   matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        endLabel                   matlab.ui.control.Label
        startLabel                 matlab.ui.control.Label
        tEditField_2               matlab.ui.control.NumericEditField
        tEditField_2Label          matlab.ui.control.Label
        tEditField                 matlab.ui.control.NumericEditField
        tEditFieldLabel            matlab.ui.control.Label
        SelectPanel_2              matlab.ui.container.Panel
        ISCheckBox                 matlab.ui.control.CheckBox
        ICCheckBox                 matlab.ui.control.CheckBox
        ILCheckBox                 matlab.ui.control.CheckBox
        IRCheckBox                 matlab.ui.control.CheckBox
        SignalDropDown_2           matlab.ui.control.DropDown
        SignalDropDown_2Label      matlab.ui.control.Label
        RLCValuesPanel_2           matlab.ui.container.Panel
        HzLabel_2                  matlab.ui.control.Label
        FrequencyEditField_2       matlab.ui.control.NumericEditField
        FrequencyEditField_2Label  matlab.ui.control.Label
        ALabel                     matlab.ui.control.Label
        IsEditField                matlab.ui.control.NumericEditField
        IsEditFieldLabel           matlab.ui.control.Label
        FLabel_2                   matlab.ui.control.Label
        HLabel_2                   matlab.ui.control.Label
        CEditField_2               matlab.ui.control.NumericEditField
        CEditField_2Label          matlab.ui.control.Label
        LEditField_2               matlab.ui.control.NumericEditField
        LEditField_2Label          matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        REditField_2               matlab.ui.control.NumericEditField
        REditField_2Label          matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Switch                     matlab.ui.control.ToggleSwitch
        SwitchLabel                matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
        methods (Access = private)
        
        function  V_C_Series(app) 
              syms t real;
              syms V_C(t)  V_S(t) ;
              R = app.REditField.Value;
              L = app.LEditField.Value;
              C = app.CEditField.Value;
              V_s = app.VsEditField.Value;
              f = app.FrequencyEditField.Value;              
              st1 = app.tEditField_3.Value;
              st2 = app.tEditField_4.Value;
              st = [st1 st2];
              if R > 0 & C > 0
                  ax = app.UIAxes;
                  ax.YLabel.String = ' Volt(V)';
                  V_S(t) = V_s*sin(2*pi*f*t);
                  D1V_C(t) = diff(V_C,t,1);
                  D2V_C(t) = diff(V_C,t,2);
                  ODE_series_VC = D2V_C + (R/L)*D1V_C + V_C(t)/(L*C) == V_S/(L*C);
                  IC_SE_VC = [V_C(0) == 0 D1V_C(0) == 0];
                  V_C_sol = simplify(dsolve(ODE_series_VC,IC_SE_VC));
                  hold(app.UIAxes,"on")
                  fplot(app.UIAxes,V_C_sol,'m',st); 
              end
        end
        
        function V_R_Series(app)
            syms t real;
            syms V_C(t) V_R(t) V_S(t);
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            f = app.FrequencyEditField.Value;
            st1 = app.tEditField_3.Value;
            st2 = app.tEditField_4.Value;
            st = [st1 st2];
            if R > 0 & C > 0
                ax = app.UIAxes;
                ax.YLabel.String = ' Volt(V)';
                V_s = app.VsEditField.Value;           
                V_S(t) = V_s*sin(2*pi *f * t);
                D1V_S(t) = diff(V_S,t,1);
                D1V_R(t) = diff(V_R,t,1);
                D2V_R(t) = diff(V_R,t,2);
                IC_SE_VR = [V_R(0) == 0 D1V_R(0) == 0];
                ODE_series_VR = ((L/R)*D2V_R(t)) + D1V_R(t)+ ((1/C)*(1/R)*V_R(t))  == D1V_S(t); 
                V_R_sol = simplify(dsolve(ODE_series_VR,IC_SE_VR));
                hold(app.UIAxes,"on")
                fplot(app.UIAxes,V_R_sol,'g',st);
                
            end
       end
        
        function V_L_Series(app)
            syms t real;
            syms V_C(t) V_R(t) V_S(t) ;
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            V_s = app.VsEditField.Value;
            f = app.FrequencyEditField.Value;
            st1 = app.tEditField_3.Value;
            st2 = app.tEditField_4.Value;
            st = [st1 st2];
            if L > 0 & C > 0 
                ax = app.UIAxes;
                ax.YLabel.String = ' Volt(V)';
                V_S(t) = V_s*sin(2*pi *f * t);
                D1V_S(t) = diff(V_S,t,1);                        
                %V_C Series
                D1V_C(t) = diff(V_C,t,1);
                D2V_C(t) = diff(V_C,t,2);
                ODE_series_VC = D2V_C + ((R/L)*D1V_C)+ (V_C(t)/(L*C)) == (V_S/(L*C));
                IC_SE_VC = [V_C(0) == 0 D1V_C(0) == 0];
                V_C_sol = simplify(dsolve(ODE_series_VC,IC_SE_VC));         
                %V_R Series
                D1V_R(t) = diff(V_R,t,1);
                D2V_R(t) = diff(V_R,t,2);
                IC_SE_VR = [V_R(0) == 0 D1V_R(0) == 0];
                ODE_series_VR = (L/R)*D2V_R(t) + D1V_R(t)+ (1/C)*(1/R)*V_R(t)  == D1V_S(t); 
                V_R_sol = dsolve(ODE_series_VR,IC_SE_VR);
                V_L(t) = V_S(t) - V_C_sol - V_R_sol;
                hold(app.UIAxes,"on")
                fplot(app.UIAxes,V_L(t),'b',st);
             end
        end
        
        function V_S_Series(app)
            syms V_S(t);
            V_s = app.VsEditField.Value;
            f = app.FrequencyEditField.Value;
            st1 = app.tEditField_3.Value;
            st2 = app.tEditField_4.Value;
            st = [st1 st2];
            ax = app.UIAxes;
            ax.YLabel.String = ' Volt(V)';
            V_S(t) = V_s*sin(2*pi *f * t);
            hold(app.UIAxes,"on")
            fplot(app.UIAxes,V_S(t),'r',st);           
        end
        
        
        function I_L_Parallel(app)
            syms t real;
            syms i_L(t) i_S(t) i_R(t) i_C(t);
            R = app.REditField_2.Value;
            L = app.LEditField_2.Value;
            C = app.CEditField_2.Value;
            f = app.FrequencyEditField_2.Value;
            i_s = app.IsEditField.Value;
            st1 = app.tEditField.Value;
            st2 = app.tEditField_2.Value;
            st = [st1 st2];
            if R > 0 
                ax = app.UIAxes;
                ax.YLabel.String = ' Current(A)';
                i_S(t) = i_s*sin(2*pi *f * t); 
                D1i_L(t) = diff(i_L,t,1);
                D2i_L(t) = diff(i_L,t,2);
                ODE_parallel_iL = (L*C)*D2i_L(t) + (L/R)*D1i_L(t) + i_L(t) == i_S(t);
                IC_PA_iL = [i_L(0) == 0 D1i_L(0) == 0];
                i_L_sol = dsolve(ODE_parallel_iL,IC_PA_iL);
                hold(app.UIAxes,"on")
                fplot(app.UIAxes,i_L_sol,'b',st);
            end
        end
   
        function I_R_Parallel(app) 
            syms t real;
            syms i_L(t) i_S(t) i_R(t) i_C(t);
            R = app.REditField_2.Value;
            L = app.LEditField_2.Value;
            st1 = app.tEditField.Value;
            st2 = app.tEditField_2.Value;
            st = [st1 st2];
            if R > 0 
                ax = app.UIAxes;
                ax.YLabel.String = ' Current(A)';
                D1i_L(t) = diff(i_L,t,1);
                i_R(t) = (L/R)*D1i_L(t);
                hold(app.UIAxes,"on")
                fplot(app.UIAxes,i_R(t),'g',st);
            end
        end
        
        
        function I_C_Parallel(app)
            syms t real;
            syms i_L(t) i_S(t) i_R(t) i_C(t);
            R = app.REditField_2.Value;
            L = app.LEditField_2.Value;
            C = app.CEditField_2.Value;
            f = app.FrequencyEditField_2.Value;
            i_s = app.IsEditField.Value;
            st1 = app.tEditField.Value;
            st2 = app.tEditField_2.Value;
            st = [st1 st2];
            if R > 0 
                ax = app.UIAxes;
                ax.YLabel.String = ' Current(A)';
                i_S(t) = i_s*sin(2*pi *f * t); 
                D1i_L(t) = diff(i_L,t,1);
                D2i_L(t) = diff(i_L,t,2);
                ODE_parallel_iL = (L*C)*D2i_L(t) + (L/R)*D1i_L(t) + i_L(t) == i_S(t);
                IC_PA_iL = [i_L(0) == 0 D1i_L(0) == 0];
                i_R(t) = (L/R)*D1i_L(t);
                i_L_sol = dsolve(ODE_parallel_iL,IC_PA_iL);
                i_C(t) = i_S(t) - i_R(t) - i_L_sol; 
                hold(app.UIAxes,"on")
                fplot(app.UIAxes,i_C(t),'m',st);
            end
        end
        
        
        function i_S_Parallel(app)
            syms t real;
            syms i_L(t) i_S(t) i_R(t) i_C(t);
            f = app.FrequencyEditField_2.Value;
            st1 = app.tEditField.Value;
            st2 = app.tEditField_2.Value;
            i_s = app.IsEditField.Value;
            st = [st1 st2];
            ax = app.UIAxes;
            ax.YLabel.String = ' Current(A)';
            i_S(t) = i_s*sin(2*pi *f * t);
            hold(app.UIAxes,"on")
            fplot(app.UIAxes,i_S(t),'r',st);
        end
        
        function V_C_tf(app)
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            VC_tf = tf([1],[L*C R*C 1])
            hold(app.UIAxes2,"on")
            bode(app.UIAxes2,VC_tf,'m');
            
        end
        
        
        function V_L_tf(app)
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            VL_tf = tf([L 0],[L+1/C R])
            hold(app.UIAxes2,"on")
            bode(app.UIAxes2,VL_tf,'b');
        end
        
        
        function V_R_tf(app)
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            VR_tf = tf([R],[L+1/C R])
            hold(app.UIAxes2,"on")
            bode(app.UIAxes2,VR_tf,'g');
        end
                        
        function i_S_tf(app)
            R = app.REditField_2.Value;
            L = app.LEditField_2.Value;
            C = app.CEditField_2.Value;
            Is_tf = tf([1],[(1/L)+C 1/R])
            hold(app.UIAxes2,"on")
            bode(app.UIAxes2,Is_tf,'r');
        end
        
        function I_L_tf(app)
            R = app.REditField_2.Value;
            L = app.LEditField_2.Value;
            C = app.CEditField_2.Value;
            IL_tf = tf([(1/C)*(1/L)],[1 (1/R)*(1/C) (1/C)*(1/L)])
            hold(app.UIAxes2,"on")
            bode(app.UIAxes2,IL_tf,'b');    
        end
              
        function I_R_tf(app)
            R = app.REditField_2.Value;
            L = app.LEditField_2.Value;
            C = app.CEditField_2.Value;
            IR_tf = tf([(1/R)],[C+1/L 1/R])
            hold(app.UIAxes2,"on")
            bode(app.UIAxes2,IR_tf,'g');     
        end
             
        function I_C_tf(app)
            R = app.REditField_2.Value;
            L = app.LEditField_2.Value;
            C = app.CEditField_2.Value;
            IC_tf = tf([C 0],[C+1/L 1/R])
            hold(app.UIAxes2,"on")
            bode(app.UIAxes2,IC_tf,'m');            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            value1 = app.VRCheckBox.Value;
            value2 = app.VLCheckBox.Value;
             value3 = app.VCCheckBox.Value;
             value4 = app.VSCheckBox.Value;
             value5 = app.IRCheckBox.Value;
             value6 = app.ILCheckBox.Value;
             value7 = app.ICCheckBox.Value;
             value8 = app.ISCheckBox.Value;

            if strcmp(value,'On')
                app.Lamp.Color = 'g';
                if value1 == 1
                   app.V_R_Series();
                   app.V_R_tf();
                end
                if value2 == 1
                   app.V_L_Series();
                   app.V_L_tf();
                end
                if value3 == 1  
                   app.V_C_Series();
                   app.V_C_tf();
                end    
                if value4 == 1
                   app.V_S_Series();
                end
                if value5 == 1 
                   app.I_R_Parallel();
                   app.I_R_tf();
                end    
                 if value6 == 1
                   app.I_L_Parallel();
                   app.I_L_tf();
                end
                if value7 == 1
                   app.I_C_Parallel();
                   app.I_C_tf();
                end
               if value8 == 1    
                   app.i_S_Parallel();
                   app.i_S_tf();
               end
            else 
                app.Lamp.Color = 'r';
                cla(app.UIAxes)
                cla(app.UIAxes2)
                clear all
                
            end
        end

        % Value changed function: VRCheckBox
        function VRCheckBoxValueChanged(app, event)
            value = app.VRCheckBox.Value;
             if value == 1
                app.REditField_2.Value = 0;
                app.LEditField_2.Value = 0;
                app.CEditField_2.Value = 0;
                app.FrequencyEditField_2.Value = 0;
                app.IsEditField.Value = 0;
                app.IRCheckBox.Value = 0;
                app.ILCheckBox.Value = 0;
                app.ICCheckBox.Value = 0;
                app.ISCheckBox.Value = 0;
             end


        end

        % Value changed function: VLCheckBox
        function VLCheckBoxValueChanged(app, event)
            value = app.VLCheckBox.Value;
            if value == 1
                app.REditField_2.Value = 0;
                app.LEditField_2.Value = 0;
                app.CEditField_2.Value = 0;
                app.FrequencyEditField_2.Value = 0;
                app.IsEditField.Value = 0;
                app.IRCheckBox.Value = 0;
                app.ILCheckBox.Value = 0;
                app.ICCheckBox.Value = 0;
                app.ISCheckBox.Value = 0;
            end
        end

        % Value changed function: VCCheckBox
        function VCCheckBoxValueChanged(app, event)
            value = app.VCCheckBox.Value;
            if value == 1
                app.REditField_2.Value = 0;
                app.LEditField_2.Value = 0;
                app.CEditField_2.Value = 0;
                app.FrequencyEditField_2.Value = 0;
                app.IsEditField.Value = 0;
                app.IRCheckBox.Value = 0;
                app.ILCheckBox.Value = 0;
                app.ICCheckBox.Value = 0;
                app.ISCheckBox.Value = 0;                
           end
        end

        % Value changed function: VSCheckBox
        function VSCheckBoxValueChanged(app, event)
            value = app.VSCheckBox.Value;
            if value == 1
                app.REditField_2.Value = 0;
                app.LEditField_2.Value = 0;
                app.CEditField_2.Value = 0;
                app.FrequencyEditField_2.Value = 0;
                app.IsEditField.Value = 0;
                app.IRCheckBox.Value = 0;
                app.ILCheckBox.Value = 0;
                app.ICCheckBox.Value = 0;
                app.ISCheckBox.Value = 0;               
           end
        end

        % Value changed function: IRCheckBox
        function IRCheckBoxValueChanged(app, event)
            value = app.IRCheckBox.Value;
            if value == 1
                app.REditField.Value = 0;
                app.LEditField.Value = 0;
                app.CEditField.Value = 0;
                app.FrequencyEditField.Value = 0;
                app.VsEditField.Value = 0;
                app.VRCheckBox.Value = 0;
                app.VLCheckBox.Value = 0;
                app.VCCheckBox.Value = 0;
                app.VSCheckBox.Value = 0;
           end
        end

        % Value changed function: ILCheckBox
        function ILCheckBoxValueChanged(app, event)
            value = app.ILCheckBox.Value;
            if value == 1
                app.REditField.Value = 0;
                app.LEditField.Value = 0;
                app.CEditField.Value = 0;
                app.FrequencyEditField.Value = 0;
                app.VsEditField.Value = 0;
                app.VRCheckBox.Value = 0;
                app.VLCheckBox.Value = 0;
                app.VCCheckBox.Value = 0;
                app.VSCheckBox.Value = 0;               
            end
        end

        % Value changed function: ICCheckBox
        function ICCheckBoxValueChanged(app, event)
            value = app.ICCheckBox.Value;
            if value == 1
                app.REditField.Value = 0;
                app.LEditField.Value = 0; 
                app.CEditField.Value = 0;
                app.FrequencyEditField.Value = 0;
                app.VsEditField.Value = 0;
                app.VRCheckBox.Value = 0;
                app.VLCheckBox.Value = 0;
                app.VCCheckBox.Value = 0;
                app.VSCheckBox.Value = 0;               
           end
        end

        % Value changed function: ISCheckBox
        function ISCheckBoxValueChanged(app, event)
            value = app.ISCheckBox.Value;
            if value == 1
                app.REditField.Value = 0;
                app.LEditField.Value = 0;
                app.CEditField.Value = 0;
                app.FrequencyEditField.Value = 0;
                app.VsEditField.Value = 0;
                app.VRCheckBox.Value = 0;
                app.VLCheckBox.Value = 0;
                app.VCCheckBox.Value = 0;
                app.VSCheckBox.Value = 0;               
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Volt(V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Response')
            xlabel(app.UIAxes2, {'Frequency(Hz)'; ''})
            ylabel(app.UIAxes2, 'Amplitude response(dB)')
            zlabel(app.UIAxes2, 'Z')
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SeriesTab = uitab(app.TabGroup);
            app.SeriesTab.Title = 'Series';
            app.RLCValuesPanel = uipanel(app.SeriesTab);
            app.RLCValuesPanel.Title = 'RLC Values ';
            app.REditFieldLabel = uilabel(app.RLCValuesPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.RLCValuesPanel, 'numeric');
            app.Label = uilabel(app.RLCValuesPanel);
            app.Label.Text = '(Ω)';
            app.LEditFieldLabel = uilabel(app.RLCValuesPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.RLCValuesPanel, 'numeric');
            app.CEditFieldLabel = uilabel(app.RLCValuesPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.RLCValuesPanel, 'numeric');
            app.HLabel = uilabel(app.RLCValuesPanel);
            app.HLabel.Text = '(H)';
            app.FLabel = uilabel(app.RLCValuesPanel);
            app.FLabel.Text = '(F)';
            app.VsEditFieldLabel = uilabel(app.RLCValuesPanel);
            app.VsEditFieldLabel.Text = 'Vs';
            app.VsEditField = uieditfield(app.RLCValuesPanel, 'numeric');
            app.VLabel = uilabel(app.RLCValuesPanel);
            app.VLabel.Text = '(V)';
            app.FrequencyEditFieldLabel = uilabel(app.RLCValuesPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.RLCValuesPanel, 'numeric');
            app.HzLabel = uilabel(app.RLCValuesPanel);
            app.HzLabel.Text = '(Hz)';
            app.SelectPanel = uipanel(app.SeriesTab);
            app.SelectPanel.Title = 'Select ';
            app.SignalDropDownLabel = uilabel(app.SelectPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.SelectPanel);
            app.SignalDropDown.Items = {'sine'};
            app.SignalDropDown.Value = 'sine';
            app.VRCheckBox = uicheckbox(app.SelectPanel);
            app.VRCheckBox.ValueChangedFcn = createCallbackFcn(app, @VRCheckBoxValueChanged, true);
            app.VRCheckBox.Text = 'V(R)';
            app.VLCheckBox = uicheckbox(app.SelectPanel);
            app.VLCheckBox.ValueChangedFcn = createCallbackFcn(app, @VLCheckBoxValueChanged, true);
            app.VLCheckBox.Text = 'V(L)';
            app.VCCheckBox = uicheckbox(app.SelectPanel);
            app.VCCheckBox.ValueChangedFcn = createCallbackFcn(app, @VCCheckBoxValueChanged, true);
            app.VCCheckBox.Text = 'V(C)';
            app.VSCheckBox = uicheckbox(app.SelectPanel);
            app.VSCheckBox.ValueChangedFcn = createCallbackFcn(app, @VSCheckBoxValueChanged, true);
            app.VSCheckBox.Text = 'V(S)';
            app.tEditField_3Label = uilabel(app.SeriesTab);
            app.tEditField_3Label.Text = 't';
            app.tEditField_3 = uieditfield(app.SeriesTab, 'numeric');
            app.tEditField_4Label = uilabel(app.SeriesTab);
            app.tEditField_4Label.Text = 't';
            app.tEditField_4 = uieditfield(app.SeriesTab, 'numeric');
            app.startLabel_2 = uilabel(app.SeriesTab);
            app.startLabel_2.Text = 'start';
            app.endLabel_2 = uilabel(app.SeriesTab);
            app.endLabel_2.Text = 'end';
            app.sLabel_3 = uilabel(app.SeriesTab);
            app.sLabel_3.Text = '(s)';
            app.sLabel_4 = uilabel(app.SeriesTab);
            app.sLabel_4.Text = '(s)';
            app.ParallelTab = uitab(app.TabGroup);
            app.ParallelTab.Title = 'Parallel';
            app.RLCValuesPanel_2 = uipanel(app.ParallelTab);
            app.RLCValuesPanel_2.Title = 'RLC Values ';
            app.REditField_2Label = uilabel(app.RLCValuesPanel_2);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.RLCValuesPanel_2, 'numeric');
            app.Label_2 = uilabel(app.RLCValuesPanel_2);
            app.Label_2.Text = '(Ω)';
            app.LEditField_2Label = uilabel(app.RLCValuesPanel_2);
            app.LEditField_2Label.Text = 'L';
            app.LEditField_2 = uieditfield(app.RLCValuesPanel_2, 'numeric');
            app.CEditField_2Label = uilabel(app.RLCValuesPanel_2);
            app.CEditField_2Label.Text = 'C';
            app.CEditField_2 = uieditfield(app.RLCValuesPanel_2, 'numeric');
            app.HLabel_2 = uilabel(app.RLCValuesPanel_2);
            app.HLabel_2.Text = '(H)';
            app.FLabel_2 = uilabel(app.RLCValuesPanel_2);
            app.FLabel_2.Text = '(F)';
            app.IsEditFieldLabel = uilabel(app.RLCValuesPanel_2);
            app.IsEditFieldLabel.Text = 'I(s)';
            app.IsEditField = uieditfield(app.RLCValuesPanel_2, 'numeric');
            app.ALabel = uilabel(app.RLCValuesPanel_2);
            app.ALabel.Text = '(A)';
            app.FrequencyEditField_2Label = uilabel(app.RLCValuesPanel_2);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.RLCValuesPanel_2, 'numeric');
            app.HzLabel_2 = uilabel(app.RLCValuesPanel_2);
            app.HzLabel_2.Text = '(Hz)';
            app.SelectPanel_2 = uipanel(app.ParallelTab);
            app.SelectPanel_2.Title = 'Select ';
            app.SignalDropDown_2Label = uilabel(app.SelectPanel_2);
            app.SignalDropDown_2Label.Text = 'Signal';
            app.SignalDropDown_2 = uidropdown(app.SelectPanel_2);
            app.SignalDropDown_2.Items = {'sine'};
            app.SignalDropDown_2.Value = 'sine';
            app.IRCheckBox = uicheckbox(app.SelectPanel_2);
            app.IRCheckBox.ValueChangedFcn = createCallbackFcn(app, @IRCheckBoxValueChanged, true);
            app.IRCheckBox.Text = 'I(R)';
            app.ILCheckBox = uicheckbox(app.SelectPanel_2);
            app.ILCheckBox.ValueChangedFcn = createCallbackFcn(app, @ILCheckBoxValueChanged, true);
            app.ILCheckBox.Text = 'I(L)';
            app.ICCheckBox = uicheckbox(app.SelectPanel_2);
            app.ICCheckBox.ValueChangedFcn = createCallbackFcn(app, @ICCheckBoxValueChanged, true);
            app.ICCheckBox.Text = 'I(C)';
            app.ISCheckBox = uicheckbox(app.SelectPanel_2);
            app.ISCheckBox.ValueChangedFcn = createCallbackFcn(app, @ISCheckBoxValueChanged, true);
            app.ISCheckBox.Text = 'I(S)';
            app.tEditFieldLabel = uilabel(app.ParallelTab);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.ParallelTab, 'numeric');
            app.tEditField_2Label = uilabel(app.ParallelTab);
            app.tEditField_2Label.Text = 't';
            app.tEditField_2 = uieditfield(app.ParallelTab, 'numeric');
            app.startLabel = uilabel(app.ParallelTab);
            app.startLabel.Text = 'start';
            app.endLabel = uilabel(app.ParallelTab);
            app.endLabel.Text = 'end';
            app.sLabel = uilabel(app.ParallelTab);
            app.sLabel.Text = '(s)';
            app.sLabel_2 = uilabel(app.ParallelTab);
            app.sLabel_2.Text = '(s)';
            app.SelectCircuitTypeLabel = uilabel(app.UIFigure);
            app.SelectCircuitTypeLabel.Text = 'Select Circuit Type';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RLC_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end